classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlasmAPP                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SavevariablesmatMenu            matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        Panel1Tab                       matlab.ui.container.Tab
        OptionsPanel                    matlab.ui.container.Panel
        UpdateButton                    matlab.ui.control.Button
        YlimitEditField                 matlab.ui.control.EditField
        YlimitEditFieldLabel            matlab.ui.control.Label
        XlimitEditField                 matlab.ui.control.EditField
        XlimitEditFieldLabel            matlab.ui.control.Label
        YtickEditField                  matlab.ui.control.EditField
        YtickEditFieldLabel             matlab.ui.control.Label
        XtickEditField                  matlab.ui.control.EditField
        XtickEditFieldLabel             matlab.ui.control.Label
        YlabelEditField                 matlab.ui.control.EditField
        YlabelEditFieldLabel            matlab.ui.control.Label
        XlabelEditField                 matlab.ui.control.EditField
        XlabelEditFieldLabel            matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Slider                          matlab.ui.control.Slider
        Axes1                           matlab.ui.control.UIAxes
        Panel2Tab                       matlab.ui.container.Tab
        OptionsPanel_2                  matlab.ui.container.Panel
        Image_2                         matlab.ui.control.Image
        UpdateButton_2                  matlab.ui.control.Button
        YlimitEditField_2               matlab.ui.control.EditField
        YlimitEditField_2Label          matlab.ui.control.Label
        XlimitEditField_2               matlab.ui.control.EditField
        XlimitEditField_2Label          matlab.ui.control.Label
        YtickEditField_2                matlab.ui.control.EditField
        YtickEditField_2Label           matlab.ui.control.Label
        XtickEditField_2                matlab.ui.control.EditField
        XtickEditField_2Label           matlab.ui.control.Label
        YlabelEditField_2               matlab.ui.control.EditField
        YlabelEditField_2Label          matlab.ui.control.Label
        XlabelEditField_2               matlab.ui.control.EditField
        XlabelEditField_2Label          matlab.ui.control.Label
        TitleEditField_2                matlab.ui.control.EditField
        TitleEditField_2Label           matlab.ui.control.Label
        Slider_2                        matlab.ui.control.Slider
        Axes2                           matlab.ui.control.UIAxes
        Panel3Tab                       matlab.ui.container.Tab
        OptionsPanel_3                  matlab.ui.container.Panel
        Image_3                         matlab.ui.control.Image
        UpdateButton_3                  matlab.ui.control.Button
        YlimitEditField_3               matlab.ui.control.EditField
        YlimitEditField_3Label          matlab.ui.control.Label
        XlimitEditField_3               matlab.ui.control.EditField
        XlimitEditField_3Label          matlab.ui.control.Label
        YtickEditField_3                matlab.ui.control.EditField
        YtickEditField_3Label           matlab.ui.control.Label
        XtickEditField_3                matlab.ui.control.EditField
        XtickEditField_3Label           matlab.ui.control.Label
        YlabelEditField_3               matlab.ui.control.EditField
        YlabelEditField_3Label          matlab.ui.control.Label
        XlabelEditField_3               matlab.ui.control.EditField
        XlabelEditField_3Label          matlab.ui.control.Label
        TitleEditField_3                matlab.ui.control.EditField
        TitleEditField_3Label           matlab.ui.control.Label
        Slider_3                        matlab.ui.control.Slider
        Axes3                           matlab.ui.control.UIAxes
        Panel4Tab                       matlab.ui.container.Tab
        OptionsPanel_4                  matlab.ui.container.Panel
        UpdateButton_4                  matlab.ui.control.Button
        Image_4                         matlab.ui.control.Image
        YlimitEditField_4               matlab.ui.control.EditField
        YlimitEditField_4Label          matlab.ui.control.Label
        XlimitEditField_4               matlab.ui.control.EditField
        XlimitEditField_4Label          matlab.ui.control.Label
        YtickEditField_4                matlab.ui.control.EditField
        YtickEditField_4Label           matlab.ui.control.Label
        XtickEditField_4                matlab.ui.control.EditField
        XtickEditField_4Label           matlab.ui.control.Label
        YlabelEditField_4               matlab.ui.control.EditField
        YlabelEditField_4Label          matlab.ui.control.Label
        XlabelEditField_4               matlab.ui.control.EditField
        XlabelEditField_4Label          matlab.ui.control.Label
        TitleEditField_4                matlab.ui.control.EditField
        TitleEditField_4Label           matlab.ui.control.Label
        Slider_4                        matlab.ui.control.Slider
        Axes4                           matlab.ui.control.UIAxes
        AllTab                          matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        Image_6                         matlab.ui.control.Image
        MaximumErrormomentumEditField   matlab.ui.control.NumericEditField
        MaximumErrormomentumEditFieldLabel  matlab.ui.control.Label
        MaximumErrorenergyEditField     matlab.ui.control.NumericEditField
        MaximumErrorenergyEditFieldLabel  matlab.ui.control.Label
        ExectutiontimeEditField         matlab.ui.control.NumericEditField
        ExectutiontimeEditField_2Label  matlab.ui.control.Label
        Image_5                         matlab.ui.control.Image
        Slider_5                        matlab.ui.control.Slider
        Axes4_1                         matlab.ui.control.UIAxes
        Axes3_1                         matlab.ui.control.UIAxes
        Axes2_1                         matlab.ui.control.UIAxes
        Axes1_1                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        P1; P2; P3; P4; E_kin; E_pot; mom; Phi_history; Eg_history; time; space; L; NPlots; dt; Nt; Motion_method; dx; Field_method; Ax; kap; Ng; WP; rhot_history; rho_back; 
        xp1_history; vp1_history; E_kin1; mom_1; V01; Vth1; N1; QM1; Q1; XP1; Mode1; rho1_history; mat1;
        xp2_history; vp2_history; E_kin2; mom_2; V02; Vth2; N2; QM2; Q2; XP2; Mode2; rho2_history; mat2; IB;
        Num_beams; Interpolation_method; 
    end
    methods (Access = private)

        % FUNCTION FOR CHARGES
        function [Q_1,Q_2,rho_back] = Charge(app,QM1,QM2)
            Q_1 = (app.WP^2*app.L)/(app.N1*QM1);   
            if QM2>0
                Q_2 = -Q_1*(app.N1/app.N2);
            end
            if QM2<0
                Q_2 = Q_1*(app.N1/app.N2);
            end
            if QM2 == 0
                Q_2 = 0;
            end  
            rho_back = (-Q_1/app.L)*(app.IB);   %Background density
        end

        function plt_labels(app,P,Title,XLabel,YLabel,Axes)
            switch P
                case 'Phase space'
                    Title.Value = 'PHASE SPACE';
                    XLabel.Value = 'Normalized space';
                    YLabel.Value = 'Normalized velocity';
                case 'Energy'
                    Title.Value = 'ENERGY VS TIME';
                    XLabel.Value = 'Normalized time';
                    YLabel.Value = 'Normalized energy';
                case 'Momentum'
                    Title.Value = 'MOMENTUM VS TIME';
                    XLabel.Value = 'Normalized time';
                    YLabel.Value = 'Normalized momentum';
                case 'Distribution function'
                    Title.Value = 'DISTRIBUTION FUNCTION';
                    XLabel.Value = 'Normalized velocity';
                    YLabel.Value = 'f(v)';
                case 'Electric potential'
                    Title.Value = 'ELECTRIC POTENTIAL VS SPACE';
                    XLabel.Value = 'Normalized space';
                    YLabel.Value = 'Normalized electric potential';
                case 'Electric field'
                    Title.Value = 'ELECTRIC FIELD VS SPACE';
                    XLabel.Value = 'Normalized space';
                    YLabel.Value = 'Normalized electric field';
                case 'Dispersion relation'
                    Title.Value = 'DISPERSION RELATION';
                    XLabel.Value =  'Normalized wavenumber';
                    YLabel.Value = 'Normalized frequency';          
            end
            Axes.Title.String = Title.Value;
            Axes.XLabel.String = XLabel.Value;
            Axes.YLabel.String = YLabel.Value;
        end
        
        % FUNCTION FOR PLOT
        function Plot_Axes(app,P,Axes,it,Title,XLimits,YLimits,XTicks,YTicks,XLabel,YLabel)
            switch P
                case 'Phase space'
                    if mod(it,app.NPlots) == 0 || it == 1
                       plot(Axes, app.xp1_history(:,it), app.vp1_history(:,it), '.' , app.xp2_history(:,it), app.vp2_history(:,it), '.')
                    end
                case 'Energy'
                    E_tot = app.E_kin + app.E_pot;
                    plot(Axes, app.time, app.E_pot,'k', app.time, app.E_kin, 'b', app.time, E_tot, 'r')
                    legend(Axes, 'E_p','E_k','E_t')
                case 'Momentum'
                    plot(Axes, app.time, app.mom)
                case 'Distribution function'
                    if mod(it,app.NPlots) == 0 || it == 1
                        x = [app.vp1_history(:,it);app.vp2_history(:,it)];
                        histogram(Axes,x,'Normalization','probability')
                    end
                case 'Electric potential'
                    if mod(it,app.NPlots) == 0 || it == 1
                        plot(Axes,(0:app.dx:app.L),app.Phi_history(:,it));
                    end
                case 'Electric field'
                    if mod(it,app.NPlots) == 0 || it == 1
                        plot(Axes,app.space',app.Eg_history(:,it));
                    end
                case 'Dispersion relation'
                    n=nextpow2(app.Ng);
                    n1=nextpow2(app.Nt);
                    kmin=2*pi/(app.dx*app.Ng)*(-app.Ng/2); kmax=2*pi/(app.dx*app.Ng)*(app.Ng/2);
                    wmin=2*pi/(app.dt*app.Nt)*(-app.Nt/2); wmax=2*pi/(app.dt*app.Nt)*(app.Nt/2);
                    kaxis=linspace(kmin,kmax,n); waxis=linspace(wmin,wmax,n1);
                    fftEg=fftshift(fft2(app.Eg_history(:,1:app.Nt)'));
                    imagesc(Axes,kaxis, waxis,abs(fftEg))
                    c = colorbar(Axes,"southoutside");
                    c.Label.String = 'Normalized electric field';
                    Axes.YDir = 'normal';
            end
            switch XLimits.Value
                case ''
                    Axes.XLimMode ='auto';
                otherwise
                    Axes.XLimMode = 'manual';
                    Axes.XLim = str2num(XLimits.Value);
            end
            switch YLimits.Value
                case ''
                    Axes.YLimMode ='auto';
                otherwise
                    Axes.YLimMode = 'manual';
                    Axes.YLim = str2num(YLimits.Value);
            end
            switch XTicks.Value
                case ''
                    Axes.XTickMode = 'auto';
                otherwise
                    Axes.XTickMode = 'manual';
                    Axes.XTick = str2num(XTicks.Value);
            end
            switch YTicks.Value
                case ''
                    Axes.YTickMode = 'auto';
                otherwise
                    Axes.YTickMode = 'manual';
                    Axes.YTick = str2num(YTicks.Value);
            end
        end

        % FUNCTION FOR POSITION
        function position = MotionX(app,position,velocity)
            switch app.Motion_method
                case 'Euler method'
                    position = position + velocity*app.dt;
                case 'Leapfrog'
                    position = position + velocity*app.dt;
                case 'Runge Kutta (RK4)'
                    k1 = velocity*app.dt;
                    k2 = (velocity+(1/2)*k1)*app.dt;
                    k3 = (velocity+(1/2)*k2)*app.dt;
                    k4 = (velocity+k3)*app.dt;
                    position = position + (1/6)*(k1+2*k2+2*k3+k4);
            end
        end

        % FUNCTION FOR VELOCITY
        function velocity = MotionV(app,velocity,QM,interp,Efieldg,it,N_particles)
            if N_particles == 0
                velocity = 0;
            else
                switch app.Motion_method
                    case 'Euler method'
                        velocity = velocity + QM*interp*Efieldg*app.dt;
                    case 'Leapfrog'
                            velocity = velocity + 0.5*QM*interp*Efieldg*app.dt;                   
                    case 'Runge Kutta (RK4)'
                        l1 = (QM*interp*Efieldg)*app.dt;
                        l2 = (QM*interp*Efieldg+(1/2)*l1)*app.dt;
                        l3 = (QM*interp*Efieldg+(1/2)*l2)*app.dt;
                        l4 = (QM*interp*Efieldg+l3)*app.dt;
                        velocity = velocity + (1/6)*(l1+2*l2+2*l3+l4);
                end
            end
        end

        % PERIODIC BOUNDARY CONDITIONS
        function variable = PeriodicBC(app,variable,LB,UB)
            % LB = Lower boundary, UB = Upper Boundary
            out=(variable<LB); variable(out) = variable(out) + UB;
            out=(variable>UB); variable(out) = variable(out) - UB;
        end

        % INTERPOLATION FUNCTION
        function interp = InterpolationF(app,position,N_particles,aux_vector)
            switch app.Interpolation_method
                case 'Nearest Grid Point (NGP)'
                        if N_particles == 0
                            interp = 0;
                        else
                            % Project Particles to grid (Subscripts denote the beams):
                            im = floor(position/app.dx);
                            ip = im + 1;
                            % im: First node. ip = i+1.
                            Project = [im;ip];                           % Concatenate nodes which particle is between.
            
                            % Boundary conditions on the projection:
                            Project = PeriodicBC(app,Project,1,app.Ng);
            
                            % Compute what fraction of the particle size that lies on the two nearest cells:
                            Fim = 1 - abs((position/app.dx) - im);       % Fim: Fraction of the particle that lies in the node i.
                            Fip = 1 - Fim;                                   % Fip: Fraction of the particle that lies in the node i1.
                            Fraction = [Fim;Fip];                            % Concatenate Fim and Fip.
                            Fraction(Fraction>0.5) = 1;
                            Fraction(Fraction<0.5) = 0;
            
                            % Interpolation matrix: Gives me a matrix of N1*Ng, were it writes the fraction of the particles in the position
                            % (p1,Project_1).
                            interp = sparse(aux_vector, Project, Fraction, N_particles, app.Ng);   % Matrix of N1*Ng, p1 : position i, Project_1 : position j.
                        end
                case 'Cloud in Cell (CIC)'
                        if N_particles == 0
                            interp = 0;
                        else
                            % Project Particles to grid (Subscripts denote the beams):
                            im = floor(position/app.dx);
                            ip = im + 1;
                            % im: First node. ip = i+1.
                            Project = [im;ip];                           % Concatenate nodes which particle is between.
            
                            % Boundary conditions on the projection:
                            Project = PeriodicBC(app,Project,1,app.Ng);
            
                            % Compute what fraction of the particle size that lies on the two nearest cells:
                            Fim = 1 - abs((position/app.dx) - im);       % Fim: Fraction of the particle that lies in the node i.
                            Fip = 1 - Fim;                                   % Fip: Fraction of the particle that lies in the node i1.
                            Fraction = [Fim;Fip];                            % Concatenate Fim and Fip.
            
                            % Interpolation matrix: Gives me a matrix of N1*Ng, were it writes the fraction of the particles in the position
                            % (p1,Project_1).
                            interp = sparse(aux_vector, Project, Fraction, N_particles, app.Ng);   % Matrix of N1*Ng, p1 : position i, Project_1 : position j.
                        end
            end         
        end

        % CHARGE DENSITY
        function rho = Charge_density(app,charge,interp)
            rho = full((charge/app.dx)*sum(interp))';
        end
        
        % FUNCTION THAT SOLVES FIELD EQUATION
        function [Phi,Eg] = Field(app,charge_density)
            switch app.Field_method
                case 'Finite Difference Method'
                    Phi = app.Ax\(-charge_density(1:app.Ng-1)*app.dx^2);
                    Phi = [Phi;0];
                    Eg = ([Phi(app.Ng); Phi(1:app.Ng-1)]-[Phi(2:app.Ng);Phi(1)])/(2*app.dx);
                case 'Fast Fourier Transform (FFT)'
                    rho_k = fft(charge_density(1:app.Ng));
                    phi_k = rho_k./app.kap.^2;
                    Phi = real(ifft(phi_k,'symmetric'));
                    Phi(app.Ng) = 0;
                    Eg_k = -1i.*app.kap.*phi_k;
                    Eg =  real(ifft(Eg_k,'symmetric'));
                case 'Direct Integration'
                    G0 = (charge_density(1)+charge_density(app.Ng))*app.dx/2;
                    G = G0;
                    G1(1) = G0;
                    for k = 2:(app.Ng)
                        G = G + (charge_density(k)+charge_density(k-1))*app.dx/2;
                        G1(k) = G;
                    end

                    F0 = (G1(1)+ G1(end))*app.dx/2;
                    F = F0;
                    F1(1) = F0;
                    for k = 2:(app.Ng)
                        F = F + (G1(k)+G1(k-1))*app.dx/2;
                        F1(k) = F;
                    end

                    Phi_prime = (1/app.L)*F1(end);
                    Eg = -Phi_prime +  G1;
                    Eg = Eg';
                    
                    Phi0 = (Eg(1)+ Eg(end))*app.dx/2;
                    Phi1 = Phi0;
                    Phi(1) = Phi0;
                    for k = 2:(app.Ng)
                        Phi1 = Phi1 + (Eg(k)+Eg(k-1))*app.dx/2;
                        Phi(k) = -Phi1;
                    end
                    Phi = Phi';
            end
        end

        
        % AUXILIARITY VECTORS
        function p = AuxVector(app,N_particles)
            if N_particles ~= 0 
                p = 1:N_particles; p = [p p];
            end
            if N_particles == 0
                p = 0;
            end
        end
        
        % INITIAL LOADING
        function [position,velocity] = InitialLoading(app,N_particles,V0,Vth,Amplitude,Mode)
            position = transpose(linspace(0,app.L-app.L/N_particles,N_particles)); 
            % Maxwellian distribution of velocities of the Beam.
            velocity = Vth*randn(N_particles,1);    
            % Adding the mean velocity.
            velocity = velocity+V0;                
            % Perturbation
            if Amplitude == 0  
            else
            position = position+Amplitude*cos(2*pi*Mode*position/app.L);
            end
        end
        
        % KINETIC ENERGY
        function Ek = Ekin(app,charge,QM,velocity,N_particles)
            if N_particles == 0
                Ek = 0;
            else
                Ek = 0.5*abs(charge/QM)*(sum(velocity.^2));
            end
        end
        
        % MOMENTUM
        function mom = momentum(app,charge,QM,velocity,N_particles)
            if N_particles == 0
                mom = 0;
            else
                mom = (charge/QM)*(sum(velocity));
            end
        end
 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, P1, P2, P3, P4, Motion_method, Field_method, Interpolation_method)
            % Maximize the screen
            %drawnow;
            %app.PlasmAPP.WindowState = 'maximized';

            tStart = tic;

            % Extract information from "data" vector.
            % Parameters
            app.L = data(1,1); app.dt = data(2,1); app.Nt = data(3,1); app.Ng = data(4,1); app.Num_beams = data(5,1); app.NPlots = data(6,1);
            % Beam 1
            app.N1 = data(7,1); app.V01 = data(8,1); app.Vth1 = data(9,1); app.QM1 = data(10,1); app.XP1 = data(11,1); app.Mode1 = data(12,1); app.WP = data(13,1);
            % Beam 2
            app.N2 = data(14,1); app.V02 = data(15,1); app.Vth2 = data(16,1); app.QM2 = data(17,1); app.XP2 = data(18,1); app.Mode2 = data(19,1); 
            app.IB = data(20,1);

            % Methods
            app.Motion_method = Motion_method;
            app.Field_method = Field_method;
            app.Interpolation_method = Interpolation_method;

            % Panels
            app.P1 = P1; app.P2 = P2; app.P3 = P3; app.P4 = P4;

            % Size of the cell
            app.dx = app.L/app.Ng;                                      
            
            % Charge
            [app.Q1,app.Q2,app.rho_back] = Charge(app,app.QM1,app.QM2);

            % Initial loading
            [xp1,vp1] = InitialLoading(app,app.N1,app.V01,app.Vth1,app.XP1,app.Mode1); 
            [xp2,vp2] = InitialLoading(app,app.N2,app.V02,app.Vth2,app.XP2,app.Mode2);

            % Prepare Diagnostics
            app.time = 0:app.dt:(app.Nt-1)*app.dt;
            app.space = 0:app.dx:app.L;
            Phi=0;   Eg=0; app.mat1 = 0; app.mat2 = 0; 
            app.mom = zeros(1, app.Nt); app.E_kin = zeros(1, app.Nt); app.E_pot = zeros(1, app.Nt);
            app.E_kin1 = zeros(1, app.Nt); app.E_kin2 = zeros(1, app.Nt); 
            app.mom_1 = zeros(1, app.Nt); app.mom_2 = zeros(1, app.Nt); 
            app.vp1_history = zeros(length(vp1), app.Nt+1); app.vp2_history = zeros(length(vp2), app.Nt+1); 
            app.xp1_history = zeros(app.N1, app.Nt+1); app.xp2_history = zeros(app.N2, app.Nt+1); 
            app.Eg_history = zeros(app.Ng+1, app.Nt+1);app.Phi_history = zeros(app.Ng+1, app.Nt+1);  
            app.rho1_history = zeros(app.Ng+1, app.Nt+1); app.rho2_history = zeros(app.Ng+1, app.Nt+1); 
            app.rhot_history = zeros(app.Ng+1, app.Nt+1);
            m = [];
            app.xp1_history(:,1) = xp1; app.vp1_history(:,1) = vp1;
            app.xp2_history(:,1) = xp2; app.vp2_history(:,1) = vp2;

            % Auxiliarity vectors
            p1 = AuxVector(app,app.N1); p2 = AuxVector(app,app.N2); 
            
            % Poisson equation preparation
            un = ones(app.Ng-1, 1); % Ng-1 * 1
            app.Ax = spdiags([un -2*un un], [-1 0 1], app.Ng-1, app.Ng-1); % Matrix that gives the indices of the Poisson equation Ng-1 * Ng-1.
            app.kap = (2*pi/app.L)*(-app.Ng/2:app.Ng/2-1);
            app.kap = fftshift(app.kap');
            app.kap(1) = 1;

            % Computational cycle
            for it = 1:app.Nt

                switch app.Motion_method
                    case 'Leapfrog'
                        vp1 = MotionV(app,vp1,app.QM1,app.mat1,Eg,it,app.N1);
                        vp2 = MotionV(app,vp2,app.QM2,app.mat2,Eg,it,app.N2);
                end

                %Updating positions
                xp1 = MotionX(app,xp1,vp1);
                xp2 = MotionX(app,xp2,vp2);

                %Apply periodic boundary conditions:
                xp1 = PeriodicBC(app,xp1,0,app.L);
                xp2 = PeriodicBC(app,xp2,0,app.L);

                %Interpolation functions
                app.mat1 = InterpolationF(app,xp1,app.N1,p1);
                app.mat2 = InterpolationF(app,xp2,app.N2,p2);

                % Charge density:
                rho1 = Charge_density(app,app.Q1,app.mat1);
                rho2 = Charge_density(app,app.Q2,app.mat2);
                rhot = rho1 + rho2 + app.rho_back;

                % Field equations:
                [Phi,Eg] = Field(app,rhot);
                %if convertCharsToStrings(app.Field_method) == "Fast Fourier Transform (FFT)"
                %     Phi(app.Ng+1) = [];
                %end

                %Updating velocity
                vp1 = MotionV(app,vp1,app.QM1,app.mat1,Eg,it,app.N1);
                vp2 = MotionV(app,vp2,app.QM2,app.mat2,Eg,it,app.N2);

                %Diagnostics:
                app.E_kin1(it) = Ekin(app,app.Q1,app.QM1,vp1,app.N1); 
                app.E_kin2(it) = Ekin(app,app.Q2,app.QM2,vp2,app.N2);
                app.E_kin(it) = app.E_kin1(it) + app.E_kin2(it) ; 

                app.mom_1(it) = momentum(app,app.Q1,app.QM1,vp1,app.N1); app.mom_2(it) = momentum(app,app.Q2,app.QM2,vp2,app.N2); 
                app.mom(it) = app.mom_1(it) + app.mom_2(it);
                app.E_pot(it) = 0.5*sum(Eg.^2)*app.dx;

                % Saving Values:
                app.vp1_history(:,it+1) = vp1;
                app.vp2_history(:,it+1) = vp2;
                app.xp1_history(:,it+1) = xp1;
                app.xp2_history(:,it+1) = xp2;
                app.Phi_history(:,it+1) = [Phi(app.Ng);Phi];
                app.Eg_history(:,it+1) = [Eg(app.Ng);Eg];
                app.rho1_history(:,it+1) = [rho1(app.Ng);rho1];
                app.rho2_history(:,it+1) = [rho2(app.Ng);rho2];
                app.rhot_history(:,it+1) = [rhot(app.Ng);rhot];
     
                % For Ticks in the Slider
                if mod(it,app.NPlots) == 0
                    m(it)=it;
                end    
            end

            % Defining limits of the sliders.
            app.Slider.Limits = [1 app.Nt]; app.Slider.MajorTicks = [1;nonzeros(m')];
            app.Slider_2.Limits = [1 app.Nt]; app.Slider_2.MajorTicks = [1;nonzeros(m')];
            app.Slider_3.Limits = [1 app.Nt]; app.Slider_3.MajorTicks = [1;nonzeros(m')];
            app.Slider_4.Limits = [1 app.Nt]; app.Slider_4.MajorTicks = [1;nonzeros(m')];
            app.Slider_5.Limits = [1 app.Nt]; app.Slider_5.MajorTicks = [1;nonzeros(m')];

            % Plot initial values.
            Plot_Axes(app,P1,app.Axes1,app.Slider.Value,app.TitleEditField,app.XlimitEditField,app.YlimitEditField,app.XtickEditField,app.YtickEditField,app.XlabelEditField,app.YlabelEditField)
            Plot_Axes(app,P2,app.Axes2,app.Slider_2.Value,app.TitleEditField_2,app.XlimitEditField_2,app.YlimitEditField_2,app.XtickEditField_2,app.YtickEditField_2,app.XlabelEditField_2,app.YlabelEditField_2)
            Plot_Axes(app,P3,app.Axes3,app.Slider_3.Value,app.TitleEditField_3,app.XlimitEditField_3,app.YlimitEditField_3,app.XtickEditField_3,app.YtickEditField_3,app.XlabelEditField_3,app.YlabelEditField_3)
            Plot_Axes(app,P4,app.Axes4,app.Slider_4.Value,app.TitleEditField_4,app.XlimitEditField_4,app.YlimitEditField_4,app.XtickEditField_4,app.YtickEditField_4,app.XlabelEditField_4,app.YlabelEditField_4)
            Plot_Axes(app,P1,app.Axes1_1,app.Slider_5.Value,app.TitleEditField,app.XlimitEditField,app.YlimitEditField,app.XtickEditField,app.YtickEditField,app.XlabelEditField,app.YlabelEditField)
            Plot_Axes(app,P2,app.Axes2_1,app.Slider_5.Value,app.TitleEditField_2,app.XlimitEditField_2,app.YlimitEditField_2,app.XtickEditField_2,app.YtickEditField_2,app.XlabelEditField_2,app.YlabelEditField_2)
            Plot_Axes(app,P3,app.Axes3_1,app.Slider_5.Value,app.TitleEditField_3,app.XlimitEditField_3,app.YlimitEditField_3,app.XtickEditField_3,app.YtickEditField_3,app.XlabelEditField_3,app.YlabelEditField_3)
            Plot_Axes(app,P4,app.Axes4_1,app.Slider_5.Value,app.TitleEditField_4,app.XlimitEditField_4,app.YlimitEditField_4,app.XtickEditField_4,app.YtickEditField_4,app.XlabelEditField_4,app.YlabelEditField_4)
            
            plt_labels(app,P1,app.TitleEditField,app.XlabelEditField,app.YlabelEditField,app.Axes1)
            plt_labels(app,P2,app.TitleEditField_2,app.XlabelEditField_2,app.YlabelEditField_2,app.Axes2)
            plt_labels(app,P3,app.TitleEditField_3,app.XlabelEditField_3,app.YlabelEditField_3,app.Axes3)
            plt_labels(app,P4,app.TitleEditField_4,app.XlabelEditField_4,app.YlabelEditField_4,app.Axes4)
            plt_labels(app,P1,app.TitleEditField,app.XlabelEditField,app.YlabelEditField,app.Axes1_1)
            plt_labels(app,P2,app.TitleEditField_2,app.XlabelEditField_2,app.YlabelEditField_2,app.Axes2_1)
            plt_labels(app,P3,app.TitleEditField_3,app.XlabelEditField_3,app.YlabelEditField_3,app.Axes3_1)
            plt_labels(app,P4,app.TitleEditField_4,app.XlabelEditField_4,app.YlabelEditField_4,app.Axes4_1)
            tEnd = toc(tStart);

            %Extra diagnostics
            app.ExectutiontimeEditField.Value = tEnd;
            Etot = app.E_kin + app.E_pot;
            app.MaximumErrorenergyEditField.Value = max((abs(Etot(1)-Etot(1:app.Nt))/Etot(1))*100);
            app.MaximumErrormomentumEditField.Value = max((abs(app.mom(2)-app.mom(2:app.Nt))/abs(app.mom(2)))*100);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            Slider1 = fix(event.Value);
            Plot_Axes(app,app.P1,app.Axes1,Slider1,app.TitleEditField,app.XlimitEditField,app.YlimitEditField,app.XtickEditField,app.YtickEditField,app.XlabelEditField,app.YlabelEditField)
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            Slider2 = fix(event.Value);
            Plot_Axes(app,app.P2,app.Axes2,Slider2,app.TitleEditField_2,app.XlimitEditField_2,app.YlimitEditField_2,app.XtickEditField_2,app.YtickEditField_2,app.XlabelEditField_2,app.YlabelEditField_2)
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            Slider3 = fix(event.Value);
            Plot_Axes(app,app.P3,app.Axes3,Slider3,app.TitleEditField_3,app.XlimitEditField_3,app.YlimitEditField_3,app.XtickEditField_3,app.YtickEditField_3,app.XlabelEditField_3,app.YlabelEditField_3)
        end

        % Value changing function: Slider_4
        function Slider_4ValueChanging(app, event)
            Slider4 = fix(event.Value);
            Plot_Axes(app,app.P4,app.Axes4,Slider4,app.TitleEditField_4,app.XlimitEditField_4,app.YlimitEditField_4,app.XtickEditField_4,app.YtickEditField_4,app.XlabelEditField_4,app.YlabelEditField_4)
        end

        % Value changing function: Slider_5
        function Slider_5ValueChanging(app, event)
            Slider5 = event.Value;
            Plot_Axes(app,app.P1,app.Axes1_1,Slider5,app.TitleEditField,app.XlimitEditField,app.YlimitEditField,app.XtickEditField,app.YtickEditField,app.XlabelEditField,app.YlabelEditField)
            Plot_Axes(app,app.P2,app.Axes2_1,Slider5,app.TitleEditField_2,app.XlimitEditField_2,app.YlimitEditField_2,app.XtickEditField_2,app.YtickEditField_2,app.XlabelEditField_2,app.YlabelEditField_2)
            Plot_Axes(app,app.P3,app.Axes3_1,Slider5,app.TitleEditField_3,app.XlimitEditField_3,app.YlimitEditField_3,app.XtickEditField_3,app.YtickEditField_3,app.XlabelEditField_3,app.YlabelEditField_3)
            Plot_Axes(app,app.P4,app.Axes4_1,Slider5,app.TitleEditField_4,app.XlimitEditField_4,app.YlimitEditField_4,app.XtickEditField_4,app.YtickEditField_4,app.XlabelEditField_4,app.YlabelEditField_4)
        end

        % Value changing function: TitleEditField
        function TitleEditFieldValueChanging(app, event)
            %Title for the first plot
            Title1 = event.Value;
            app.Axes1.Title.String = Title1;
            app.Axes1_1.Title.String = Title1;
        end

        % Value changing function: XlabelEditField
        function XlabelEditFieldValueChanging(app, event)
            % X label for the first plot changed when the value is
            Xlabel1 = event.Value;
            app.Axes1.XLabel.String = Xlabel1;
            app.Axes1_1.XLabel.String = Xlabel1;
        end

        % Value changing function: YlabelEditField
        function YlabelEditFieldValueChanging(app, event)
            Ylabel1 = event.Value;
            app.Axes1.YLabel.String = Ylabel1;
            app.Axes1_1.YLabel.String = Ylabel1;
        end

        % Value changing function: YlabelEditField_2
        function YlabelEditField_2ValueChanging(app, event)
            Ylabel2 = event.Value;
            app.Axes2.YLabel.String = Ylabel2;
            app.Axes2_1.YLabel.String = Ylabel2;
        end

        % Value changing function: XlabelEditField_2
        function XlabelEditField_2ValueChanging(app, event)
            Xlabel2 = event.Value;
            app.Axes2.XLabel.String = Xlabel2;
            app.Axes2_1.XLabel.String = Xlabel2;
        end

        % Value changing function: TitleEditField_2
        function TitleEditField_2ValueChanging(app, event)
            Title2 = event.Value;
            app.Axes2.Title.String = Title2;
            app.Axes2_1.Title.String = Title2;
        end

        % Value changing function: YlabelEditField_3
        function YlabelEditField_3ValueChanging(app, event)
            Ylabel3 = event.Value;
            app.Axes3.YLabel.String = Ylabel3;
            app.Axes3_1.YLabel.String = Ylabel3;
        end

        % Value changing function: XlabelEditField_3
        function XlabelEditField_3ValueChanging(app, event)
            Xlabel3 = event.Value;
            app.Axes3.XLabel.String = Xlabel3;
            app.Axes3_1.XLabel.String = Xlabel3;
        end

        % Value changing function: TitleEditField_3
        function TitleEditField_3ValueChanging(app, event)
            Title3= event.Value;
            app.Axes3.Title.String = Title3;
            app.Axes3_1.Title.String = Title3;
        end

        % Value changing function: YlabelEditField_4
        function YlabelEditField_4ValueChanging(app, event)
            Ylabel4 = event.Value;
            app.Axes4.YLabel.String = Ylabel4;
            app.Axes4_1.YLabel.String = Ylabel4;
        end

        % Value changing function: XlabelEditField_4
        function XlabelEditField_4ValueChanging(app, event)
            Xlabel4 = event.Value;
            app.Axes4.XLabel.String = Xlabel4;
            app.Axes4_1.XLabel.String = Xlabel4;
        end

        % Value changing function: TitleEditField_4
        function TitleEditField_4ValueChanging(app, event)
            Title4 = event.Value;
            app.Axes4.Title.String = Title4;
            app.Axes4_1.Title.String = Title4;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Function that update the variables of the first panel
            switch app.XlimitEditField.Value
                case ''
                    app.Axes1.XLimMode ='auto';
                    app.Axes1_1.XLimMode ='auto';
                otherwise
                    app.Axes1.XLimMode = 'manual';
                    app.Axes1.XLim = str2num(app.XlimitEditField.Value);
                    app.Axes1_1.XLimMode = 'manual';
                    app.Axes1_1.XLim = str2num(app.XlimitEditField.Value);
            end

            switch app.YlimitEditField.Value
                case ''
                    app.Axes1.YLimMode ='auto';
                    app.Axes1_1.YLimMode ='auto';
                otherwise
                    app.Axes1.YLimMode = 'manual';
                    app.Axes1.YLim = str2num(app.YlimitEditField.Value);
                     app.Axes1_1.YLimMode = 'manual';
                    app.Axes1_1.YLim = str2num(app.YlimitEditField.Value);
            end

            switch app.XtickEditField.Value
                case ''
                    app.Axes1.XTickMode = 'auto';
                    app.Axes1_1.XTickMode = 'auto';
                otherwise
                    app.Axes1.XTickMode = 'manual';
                    app.Axes1.XTick = str2num(app.XtickEditField.Value);
                    app.Axes1_1.XTickMode = 'manual';
                    app.Axes1_1.XTick = str2num(app.XtickEditField.Value);
            end

            switch app.YtickEditField.Value
                case ''
                    app.Axes1.YTickMode = 'auto';
                    app.Axes1_1.YTickMode = 'auto';
                otherwise
                    app.Axes1.YTickMode = 'manual';
                    app.Axes1.YTick = str2num(app.YtickEditField.Value);
                    app.Axes1_1.YTickMode = 'manual';
                    app.Axes1_1.YTick = str2num(app.YtickEditField.Value);
            end
            
        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
            switch app.XlimitEditField_2.Value
                case ''
                    app.Axes2.XLimMode ='auto';
                    app.Axes2_1.XLimMode ='auto';
                otherwise
                    app.Axes2.XLimMode = 'manual';
                    app.Axes2.XLim = str2num(app.XlimitEditField_2.Value);
                    app.Axes2_1.XLimMode = 'manual';
                    app.Axes2_1.XLim = str2num(app.XlimitEditField_2.Value);
            end

            switch app.YlimitEditField_2.Value
                case ''
                    app.Axes2.YLimMode ='auto';
                    app.Axes2_1.YLimMode ='auto';
                otherwise
                    app.Axes2.YLimMode = 'manual';
                    app.Axes2.YLim = str2num(app.YlimitEditField_2.Value);
                    app.Axes2_1.YLimMode = 'manual';
                    app.Axes2_1.YLim = str2num(app.YlimitEditField_2.Value);
            end

            switch app.XtickEditField_2.Value
                case ''
                    app.Axes2.XTickMode = 'auto';
                    app.Axes2_1.XTickMode = 'auto';
                otherwise
                    app.Axes2.XTickMode = 'manual';
                    app.Axes2.XTick = str2num(app.XtickEditField_2.Value);
                    app.Axes2_1.XTickMode = 'manual';
                    app.Axes2_1.XTick = str2num(app.XtickEditField_2.Value);
            end

            switch app.YtickEditField_2.Value
                case ''
                    app.Axes2.YTickMode = 'auto';
                    app.Axes2_1.YTickMode = 'auto';
                otherwise
                    app.Axes2.YTickMode = 'manual';
                    app.Axes2.YTick = str2num(app.YtickEditField_2.Value);
                    app.Axes2_1.YTickMode = 'manual';
                    app.Axes2_1.YTick = str2num(app.YtickEditField_2.Value);
            end
        end

        % Button pushed function: UpdateButton_3
        function UpdateButton_3Pushed(app, event)
            switch app.XlimitEditField_3.Value
                case ''
                    app.Axes3.XLimMode ='auto';
                    app.Axes3_1.XLimMode ='auto';
                otherwise
                    app.Axes3.XLimMode = 'manual';
                    app.Axes3.XLim = str2num(app.XlimitEditField_3.Value);
                    app.Axes3_1.XLimMode = 'manual';
                    app.Axes3_1.XLim = str2num(app.XlimitEditField_3.Value);
            end

            switch app.YlimitEditField_3.Value
                case ''
                    app.Axes3.YLimMode ='auto';
                    app.Axes3_1.YLimMode ='auto';
                otherwise
                    app.Axes3.YLimMode = 'manual';
                    app.Axes3.YLim = str2num(app.YlimitEditField_3.Value);
                    app.Axes3_1.YLimMode = 'manual';
                    app.Axes3_1.YLim = str2num(app.YlimitEditField_3.Value);
            end

            switch app.XtickEditField_3.Value
                case ''
                    app.Axes3.XTickMode = 'auto';
                    app.Axes3_1.XTickMode = 'auto';
                otherwise
                    app.Axes3.XTickMode = 'manual';
                    app.Axes3.XTick = str2num(app.XtickEditField_3.Value);
                    app.Axes3_1.XTickMode = 'manual';
                    app.Axes3_1.XTick = str2num(app.XtickEditField_3.Value);
            end

            switch app.YtickEditField_3.Value
                case ''
                    app.Axes3.YTickMode = 'auto';
                    app.Axes3_1.YTickMode = 'auto';
                otherwise
                    app.Axes3.YTickMode = 'manual';
                    app.Axes3.YTick = str2num(app.YtickEditField_3.Value);
                    app.Axes3_1.YTickMode = 'manual';
                    app.Axes3_1.YTick = str2num(app.YtickEditField_3.Value);
            end
        end

        % Button pushed function: UpdateButton_4
        function UpdateButton_4Pushed(app, event)
            switch app.XlimitEditField_4.Value
                case ''
                    app.Axes4.XLimMode ='auto';
                    app.Axes4_1.XLimMode ='auto';
                otherwise
                    app.Axes4.XLimMode = 'manual';
                    app.Axes4.XLim = str2num(app.XlimitEditField_4.Value);
                    app.Axes4_1.XLimMode = 'manual';
                    app.Axes4_1.XLim = str2num(app.XlimitEditField_4.Value);
            end

            switch app.YlimitEditField_4.Value
                case ''
                    app.Axes4.YLimMode ='auto';
                    app.Axes4_1.YLimMode ='auto';
                otherwise
                    app.Axes4.YLimMode = 'manual';
                    app.Axes4.YLim = str2num(app.YlimitEditField_4.Value);
                    app.Axes4_1.YLimMode = 'manual';
                    app.Axes4_1.YLim = str2num(app.YlimitEditField_4.Value);
            end

            switch app.XtickEditField_4.Value
                case ''
                    app.Axes4.XTickMode = 'auto';
                    app.Axes4_1.XTickMode = 'auto';
                otherwise
                    app.Axes4.XTickMode = 'manual';
                    app.Axes4.XTick = str2num(app.XtickEditField_4.Value);
                    app.Axes4_1.XTickMode = 'manual';
                    app.Axes4_1.XTick = str2num(app.XtickEditField_4.Value);
            end

            switch app.YtickEditField_4.Value
                case ''
                    app.Axes4.YTickMode = 'auto';
                    app.Axes4_1.YTickMode = 'auto';
                otherwise
                    app.Axes4.YTickMode = 'manual';
                    app.Axes4.YTick = str2num(app.YtickEditField_4.Value);
                    app.Axes4_1.YTickMode = 'manual';
                    app.Axes4_1.YTick = str2num(app.YtickEditField_4.Value);
            end
        end

        % Menu selected function: SavevariablesmatMenu
        function SavevariablesmatMenuSelected(app, event)
            [fname,pth] = uiputfile('.mat'); % Type in name of file.
            if fname == 0
                return;
            else
                % Beam 1
                xp1_hist = app.xp1_history; vp1_hist = app.vp1_history; Ekin1 = app.E_kin1; mom1 = app.mom_1; V_01 = app.V01; Vth_1 = app.Vth1;
                N_1 = app.N1; QM_1 = app.QM1; Q_1 = app.Q1; XP_1 = app.XP1; Mode_1 = app.Mode1; rho1_hist = app.rho1_history; mat_1 = app.mat1; WP_1 = app.WP;
                % Beam 2
                xp2_hist = app.xp2_history; vp2_hist = app.vp2_history; Ekin2 = app.E_kin2; mom2 = app.mom_2; V_02 = app.V02; Vth_2 = app.Vth2;
                N_2 = app.N2; QM_2 = app.QM2; Q_2 = app.Q2; XP_2 = app.XP2; Mode_2 = app.Mode2; rho2_hist = app.rho2_history; mat_2 = app.mat2;
                
                % General
                rhoback = app.rho_back; rhot_hist = app.rhot_history; N_g = app.Ng; Dx = app.dx; NT = app.Nt; Dt = app.dt; Nplots = app.NPlots; Length = app.L; Space = app.space; 
                Time = app.time; Eg_hist = app.Eg_history; Phi_hist = app.Phi_history; Epot = app.E_pot; Ekin =app.E_kin; Mom = app.mom;

                save([pth,fname],"xp1_hist","vp1_hist","Ekin1","mom1","V_01","Vth_1","N_1","QM_1","Q_1","XP_1","Mode_1","rho1_hist","mat_1","WP_1", ...
                    "xp2_hist", "vp2_hist", "Ekin2", "mom2", "V_02", "Vth_2", "N_2", "QM_2", "Q_2", "XP_2", "Mode_2", "rho2_hist", "mat_2", ...
                    "rhoback","rhot_hist","N_g","Dx","NT","Dt","Nplots","Length","Space","Time","Eg_hist","Phi_hist","Epot","Ekin","Mom")
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            Inputs
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            Inputs
        end

        % Image clicked function: Image_3
        function Image_3Clicked(app, event)
            Inputs
        end

        % Image clicked function: Image_4
        function Image_4Clicked(app, event)
            Inputs
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlasmAPP = uifigure('Visible', 'off');
            app.PlasmAPP.Name = 'PlasmAPP';
            app.PlasmAPP.Icon = 'PLASMAPP (2).gif';
            app.FileMenu = uimenu(app.PlasmAPP);
            app.FileMenu.Text = 'File';
            app.SavevariablesmatMenu = uimenu(app.FileMenu);
            app.SavevariablesmatMenu.MenuSelectedFcn = createCallbackFcn(app, @SavevariablesmatMenuSelected, true);
            app.SavevariablesmatMenu.Text = 'Save variables .mat';
            app.TabGroup = uitabgroup(app.PlasmAPP);
            app.Panel1Tab = uitab(app.TabGroup);
            app.Panel1Tab.Title = 'Panel 1';
            app.Axes1 = uiaxes(app.Panel1Tab);
            title(app.Axes1, 'Title')
            xlabel(app.Axes1, 'X')
            ylabel(app.Axes1, 'Y')
            app.Axes1.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Axes1.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Slider = uislider(app.Panel1Tab);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Tooltip = {'Please click on the number of the iteration you wish to observe'};
            app.Slider.Value = 1;
            app.OptionsPanel = uipanel(app.Panel1Tab);
            app.OptionsPanel.Title = 'Options';
            app.Image = uiimage(app.OptionsPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'PLASMAPP (1).gif';
            app.TitleEditFieldLabel = uilabel(app.OptionsPanel);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.OptionsPanel, 'text');
            app.TitleEditField.ValueChangingFcn = createCallbackFcn(app, @TitleEditFieldValueChanging, true);
            app.TitleEditField.Tooltip = {'Ttle of the plot'};
            app.XlabelEditFieldLabel = uilabel(app.OptionsPanel);
            app.XlabelEditFieldLabel.Text = 'X label';
            app.XlabelEditField = uieditfield(app.OptionsPanel, 'text');
            app.XlabelEditField.ValueChangingFcn = createCallbackFcn(app, @XlabelEditFieldValueChanging, true);
            app.XlabelEditField.Tooltip = {'Label for the X axis'};
            app.YlabelEditFieldLabel = uilabel(app.OptionsPanel);
            app.YlabelEditFieldLabel.Text = 'Y label';
            app.YlabelEditField = uieditfield(app.OptionsPanel, 'text');
            app.YlabelEditField.ValueChangingFcn = createCallbackFcn(app, @YlabelEditFieldValueChanging, true);
            app.YlabelEditField.Tooltip = {'Label for the Y axis'};
            app.XtickEditFieldLabel = uilabel(app.OptionsPanel);
            app.XtickEditFieldLabel.Text = 'X tick';
            app.XtickEditField = uieditfield(app.OptionsPanel, 'text');
            app.XtickEditField.Tooltip = {'Define the X axis tick values usig a vector:'; ''; 'lower_limit:step:upper_limit'; ''; 'For example:'; '-1:0.5:1'; ''; 'If there is no value in the edit field mode for the tick values will be automatic.'};
            app.YtickEditFieldLabel = uilabel(app.OptionsPanel);
            app.YtickEditFieldLabel.Text = 'Y tick';
            app.YtickEditField = uieditfield(app.OptionsPanel, 'text');
            app.YtickEditField.Tooltip = {'Define the Y axis tick values usig a vector:'; ''; 'lower_limit:step:upper_limit'; ''; 'For example:'; '-1:0.5:1'; ''; 'If there is no value in the edit field mode for the tick values will be automatic.'};
            app.XlimitEditFieldLabel = uilabel(app.OptionsPanel);
            app.XlimitEditFieldLabel.Text = 'X limit';
            app.XlimitEditField = uieditfield(app.OptionsPanel, 'text');
            app.XlimitEditField.Tooltip = {'Define the limits of the X axis usig a 1X2 vector:'; ''; '[lower_limit upper_limit]'; ''; 'For example:'; '[0 5]'; ''; 'If there is no value in the edit field the limits mode will be automatic.'};
            app.YlimitEditFieldLabel = uilabel(app.OptionsPanel);
            app.YlimitEditFieldLabel.Text = 'Y limit';
            app.YlimitEditField = uieditfield(app.OptionsPanel, 'text');
            app.YlimitEditField.Tooltip = {'Define the limits of the Y axis usig a 1X2 vector:'; ''; '[lower_limit upper_limit]'; ''; 'For example:'; '[0 5]'; ''; 'If there is no value in the edit field the limits mode will be automatic.'};
            app.UpdateButton = uibutton(app.OptionsPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.Panel2Tab = uitab(app.TabGroup);
            app.Panel2Tab.Title = 'Panel 2';
            app.Axes2 = uiaxes(app.Panel2Tab);
            title(app.Axes2, 'Title')
            xlabel(app.Axes2, 'X')
            ylabel(app.Axes2, 'Y')
            app.Slider_2 = uislider(app.Panel2Tab);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_2.Tooltip = {'Please click on the number of the iteration you wish to observe'};
            app.Slider_2.Value = 1;
            app.OptionsPanel_2 = uipanel(app.Panel2Tab);
            app.OptionsPanel_2.Title = 'Options';
            app.TitleEditField_2Label = uilabel(app.OptionsPanel_2);
            app.TitleEditField_2Label.Text = 'Title';
            app.TitleEditField_2 = uieditfield(app.OptionsPanel_2, 'text');
            app.TitleEditField_2.ValueChangingFcn = createCallbackFcn(app, @TitleEditField_2ValueChanging, true);
            app.XlabelEditField_2Label = uilabel(app.OptionsPanel_2);
            app.XlabelEditField_2Label.Text = 'X label';
            app.XlabelEditField_2 = uieditfield(app.OptionsPanel_2, 'text');
            app.XlabelEditField_2.ValueChangingFcn = createCallbackFcn(app, @XlabelEditField_2ValueChanging, true);
            app.XlabelEditField_2.Tooltip = {'Label for the X axis'};
            app.YlabelEditField_2Label = uilabel(app.OptionsPanel_2);
            app.YlabelEditField_2Label.Text = 'Y label';
            app.YlabelEditField_2 = uieditfield(app.OptionsPanel_2, 'text');
            app.YlabelEditField_2.ValueChangingFcn = createCallbackFcn(app, @YlabelEditField_2ValueChanging, true);
            app.YlabelEditField_2.Tooltip = {'Label for the Y axis'};
            app.XtickEditField_2Label = uilabel(app.OptionsPanel_2);
            app.XtickEditField_2Label.Text = 'X tick';
            app.XtickEditField_2 = uieditfield(app.OptionsPanel_2, 'text');
            app.XtickEditField_2.Tooltip = {'Define the X axis tick values usig a vector:'; ''; 'lower_limit:step:upper_limit'; ''; 'For example:'; '-1:0.5:1'; ''; 'If there is no value in the edit field mode for the tick values will be automatic.'};
            app.YtickEditField_2Label = uilabel(app.OptionsPanel_2);
            app.YtickEditField_2Label.Text = 'Y tick';
            app.YtickEditField_2 = uieditfield(app.OptionsPanel_2, 'text');
            app.YtickEditField_2.Tooltip = {'Define the Y axis tick values usig a vector:'; ''; 'lower_limit:step:upper_limit'; ''; 'For example:'; '-1:0.5:1'; ''; 'If there is no value in the edit field mode for the tick values will be automatic.'};
            app.XlimitEditField_2Label = uilabel(app.OptionsPanel_2);
            app.XlimitEditField_2Label.Text = 'X limit';
            app.XlimitEditField_2 = uieditfield(app.OptionsPanel_2, 'text');
            app.XlimitEditField_2.Tooltip = {'Define the limits of the X axis usig a 1X2 vector:'; ''; '[lower_limit upper_limit]'; ''; 'For example:'; '[0 5]'; ''; 'If there is no value in the edit field the limits mode will be automatic.'};
            app.YlimitEditField_2Label = uilabel(app.OptionsPanel_2);
            app.YlimitEditField_2Label.Text = 'Y limit';
            app.YlimitEditField_2 = uieditfield(app.OptionsPanel_2, 'text');
            app.YlimitEditField_2.Tooltip = {'Define the limits of the Y axis usig a 1X2 vector:'; ''; '[lower_limit upper_limit]'; ''; 'For example:'; '[0 5]'; ''; 'If there is no value in the edit field the limits mode will be automatic.'};
            app.UpdateButton_2 = uibutton(app.OptionsPanel_2, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Text = 'Update';
            app.Image_2 = uiimage(app.OptionsPanel_2);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = 'PLASMAPP (1).gif';
            app.Panel3Tab = uitab(app.TabGroup);
            app.Panel3Tab.Tooltip = {'Please click on the number of the iteration you wish to observe'};
            app.Panel3Tab.Title = 'Panel 3';
            app.Axes3 = uiaxes(app.Panel3Tab);
            title(app.Axes3, 'Title')
            xlabel(app.Axes3, 'X')
            ylabel(app.Axes3, 'Y')
            app.Slider_3 = uislider(app.Panel3Tab);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Slider_3.MinorTicks = [];
            app.Slider_3.Value = 1;
            app.OptionsPanel_3 = uipanel(app.Panel3Tab);
            app.OptionsPanel_3.Title = 'Options';
            app.TitleEditField_3Label = uilabel(app.OptionsPanel_3);
            app.TitleEditField_3Label.Text = 'Title';
            app.TitleEditField_3 = uieditfield(app.OptionsPanel_3, 'text');
            app.TitleEditField_3.ValueChangingFcn = createCallbackFcn(app, @TitleEditField_3ValueChanging, true);
            app.XlabelEditField_3Label = uilabel(app.OptionsPanel_3);
            app.XlabelEditField_3Label.Text = 'X label';
            app.XlabelEditField_3 = uieditfield(app.OptionsPanel_3, 'text');
            app.XlabelEditField_3.ValueChangingFcn = createCallbackFcn(app, @XlabelEditField_3ValueChanging, true);
            app.XlabelEditField_3.Tooltip = {'Label for the X axis'};
            app.YlabelEditField_3Label = uilabel(app.OptionsPanel_3);
            app.YlabelEditField_3Label.Text = 'Y label';
            app.YlabelEditField_3 = uieditfield(app.OptionsPanel_3, 'text');
            app.YlabelEditField_3.ValueChangingFcn = createCallbackFcn(app, @YlabelEditField_3ValueChanging, true);
            app.YlabelEditField_3.Tooltip = {'Label for the Y axis'};
            app.XtickEditField_3Label = uilabel(app.OptionsPanel_3);
            app.XtickEditField_3Label.Text = 'X tick';
            app.XtickEditField_3 = uieditfield(app.OptionsPanel_3, 'text');
            app.XtickEditField_3.Tooltip = {'Define the Y axis tick values usig a vector:'; ''; 'lower_limit:step:upper_limit'; ''; 'For example:'; '-1:0.5:1'; ''; 'If there is no value in the edit field mode for the tick values will be automatic.'};
            app.YtickEditField_3Label = uilabel(app.OptionsPanel_3);
            app.YtickEditField_3Label.Text = 'Y tick';
            app.YtickEditField_3 = uieditfield(app.OptionsPanel_3, 'text');
            app.YtickEditField_3.Tooltip = {'Define the Y axis tick values usig a vector:'; ''; 'lower_limit:step:upper_limit'; ''; 'For example:'; '-1:0.5:1'; ''; 'If there is no value in the edit field mode for the tick values will be automatic.'};
            app.XlimitEditField_3Label = uilabel(app.OptionsPanel_3);
            app.XlimitEditField_3Label.Text = 'X limit';
            app.XlimitEditField_3 = uieditfield(app.OptionsPanel_3, 'text');
            app.XlimitEditField_3.Tooltip = {'Define the limits of the X axis usig a 1X2 vector:'; ''; '[lower_limit upper_limit]'; ''; 'For example:'; '[0 5]'; ''; 'If there is no value in the edit field the limits mode will be automatic.'};
            app.YlimitEditField_3Label = uilabel(app.OptionsPanel_3);
            app.YlimitEditField_3Label.Text = 'Y limit';
            app.YlimitEditField_3 = uieditfield(app.OptionsPanel_3, 'text');
            app.YlimitEditField_3.Tooltip = {'Define the limits of the Y axis usig a 1X2 vector:'; ''; '[lower_limit upper_limit]'; ''; 'For example:'; '[0 5]'; ''; 'If there is no value in the edit field the limits mode will be automatic.'};
            app.UpdateButton_3 = uibutton(app.OptionsPanel_3, 'push');
            app.UpdateButton_3.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_3Pushed, true);
            app.UpdateButton_3.Text = 'Update';
            app.Image_3 = uiimage(app.OptionsPanel_3);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @Image_3Clicked, true);
            app.Image_3.ImageSource = 'PLASMAPP (1).gif';
            app.Panel4Tab = uitab(app.TabGroup);
            app.Panel4Tab.Title = 'Panel 4';
            app.Axes4 = uiaxes(app.Panel4Tab);
            title(app.Axes4, 'Title')
            xlabel(app.Axes4, 'X')
            ylabel(app.Axes4, 'Y')
            app.Slider_4 = uislider(app.Panel4Tab);
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            app.Slider_4.MinorTicks = [];
            app.Slider_4.Tooltip = {'Please click on the number of the iteration you wish to observe'};
            app.Slider_4.Value = 1;
            app.OptionsPanel_4 = uipanel(app.Panel4Tab);
            app.OptionsPanel_4.Title = 'Options';
            app.TitleEditField_4Label = uilabel(app.OptionsPanel_4);
            app.TitleEditField_4Label.Text = 'Title';
            app.TitleEditField_4 = uieditfield(app.OptionsPanel_4, 'text');
            app.TitleEditField_4.ValueChangingFcn = createCallbackFcn(app, @TitleEditField_4ValueChanging, true);
            app.TitleEditField_4.Tooltip = {'Ttle of the plot'};
            app.XlabelEditField_4Label = uilabel(app.OptionsPanel_4);
            app.XlabelEditField_4Label.Text = 'X label';
            app.XlabelEditField_4 = uieditfield(app.OptionsPanel_4, 'text');
            app.XlabelEditField_4.ValueChangingFcn = createCallbackFcn(app, @XlabelEditField_4ValueChanging, true);
            app.XlabelEditField_4.Tooltip = {'Label for the X axis'};
            app.YlabelEditField_4Label = uilabel(app.OptionsPanel_4);
            app.YlabelEditField_4Label.Text = 'Y label';
            app.YlabelEditField_4 = uieditfield(app.OptionsPanel_4, 'text');
            app.YlabelEditField_4.ValueChangingFcn = createCallbackFcn(app, @YlabelEditField_4ValueChanging, true);
            app.YlabelEditField_4.Tooltip = {'Label for the Y axis'};
            app.XtickEditField_4Label = uilabel(app.OptionsPanel_4);
            app.XtickEditField_4Label.Text = 'X tick';
            app.XtickEditField_4 = uieditfield(app.OptionsPanel_4, 'text');
            app.XtickEditField_4.Tooltip = {'Define the X axis tick values usig a vector:'; ''; 'lower_limit:step:upper_limit'; ''; 'For example:'; '-1:0.5:1'; ''; 'If there is no value in the edit field mode for the tick values will be automatic.'};
            app.YtickEditField_4Label = uilabel(app.OptionsPanel_4);
            app.YtickEditField_4Label.Text = 'Y tick';
            app.YtickEditField_4 = uieditfield(app.OptionsPanel_4, 'text');
            app.YtickEditField_4.Tooltip = {'Define the Y axis tick values usig a vector:'; ''; 'lower_limit:step:upper_limit'; ''; 'For example:'; '-1:0.5:1'; ''; 'If there is no value in the edit field mode for the tick values will be automatic.'};
            app.XlimitEditField_4Label = uilabel(app.OptionsPanel_4);
            app.XlimitEditField_4Label.Text = 'X limit';
            app.XlimitEditField_4 = uieditfield(app.OptionsPanel_4, 'text');
            app.XlimitEditField_4.Tooltip = {'Define the limits of the X axis usig a 1X2 vector:'; ''; '[lower_limit upper_limit]'; ''; 'For example:'; '[0 5]'; ''; 'If there is no value in the edit field the limits mode will be automatic.'};
            app.YlimitEditField_4Label = uilabel(app.OptionsPanel_4);
            app.YlimitEditField_4Label.Text = 'Y limit';
            app.YlimitEditField_4 = uieditfield(app.OptionsPanel_4, 'text');
            app.YlimitEditField_4.Tooltip = {'Define the limits of the Y axis usig a 1X2 vector:'; ''; '[lower_limit upper_limit]'; ''; 'For example:'; '[0 5]'; ''; 'If there is no value in the edit field the limits mode will be automatic.'};
            app.Image_4 = uiimage(app.OptionsPanel_4);
            app.Image_4.ImageClickedFcn = createCallbackFcn(app, @Image_4Clicked, true);
            app.Image_4.ImageSource = 'PLASMAPP (1).gif';
            app.UpdateButton_4 = uibutton(app.OptionsPanel_4, 'push');
            app.UpdateButton_4.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_4Pushed, true);
            app.UpdateButton_4.Text = 'Update';
            app.AllTab = uitab(app.TabGroup);
            app.AllTab.Title = 'All';
            app.Axes1_1 = uiaxes(app.AllTab);
            title(app.Axes1_1, 'Title')
            xlabel(app.Axes1_1, 'X')
            ylabel(app.Axes1_1, 'Y')
            app.Axes2_1 = uiaxes(app.AllTab);
            title(app.Axes2_1, 'Title')
            xlabel(app.Axes2_1, 'X')
            ylabel(app.Axes2_1, 'Y')
            app.Axes3_1 = uiaxes(app.AllTab);
            title(app.Axes3_1, 'Title')
            xlabel(app.Axes3_1, 'X')
            ylabel(app.Axes3_1, 'Y')
            app.Axes4_1 = uiaxes(app.AllTab);
            title(app.Axes4_1, 'Title')
            xlabel(app.Axes4_1, 'X')
            ylabel(app.Axes4_1, 'Y')
            app.Slider_5 = uislider(app.AllTab);
            app.Slider_5.ValueChangingFcn = createCallbackFcn(app, @Slider_5ValueChanging, true);
            app.Slider_5.MinorTicks = [];
            app.Slider_5.Value = 1;
            app.Image_5 = uiimage(app.AllTab);
            app.Image_5.ImageSource = 'PLASMAPP (1).gif';
            app.Panel = uipanel(app.AllTab);
            app.ExectutiontimeEditField_2Label = uilabel(app.Panel);
            app.ExectutiontimeEditField_2Label.Text = 'Exectution time';
            app.ExectutiontimeEditField = uieditfield(app.Panel, 'numeric');
            app.ExectutiontimeEditField.Editable = 'off';
            app.MaximumErrorenergyEditFieldLabel = uilabel(app.Panel);
            app.MaximumErrorenergyEditFieldLabel.Text = 'Maximum % Error energy';
            app.MaximumErrorenergyEditField = uieditfield(app.Panel, 'numeric');
            app.MaximumErrorenergyEditField.Editable = 'off';
            app.MaximumErrormomentumEditFieldLabel = uilabel(app.Panel);
            app.MaximumErrormomentumEditFieldLabel.Text = 'Maximum % Error momentum';
            app.MaximumErrormomentumEditField = uieditfield(app.Panel, 'numeric');
            app.MaximumErrormomentumEditField.Editable = 'off';
            app.Image_6 = uiimage(app.Panel);
            app.Image_6.ImageSource = 'PLASMAPP (1).gif';
            % Show the figure after all components are created
            app.PlasmAPP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlasmAPP)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlasmAPP)
        end
    end
end